classdef CompImages < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageViewerSamDoncheUIFigure  matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadImage1REFMenu             matlab.ui.container.Menu
        LoadImage2Menu                matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        SagittalViewPanel             matlab.ui.container.Panel
        UIAxesSagittalImg1            matlab.ui.control.UIAxes
        UIAxesSagittalImg2            matlab.ui.control.UIAxes
        UIAxesSagittalFuse            matlab.ui.control.UIAxes
        SliceEditFieldLabel_2         matlab.ui.control.Label
        SliceSagittalField            matlab.ui.control.NumericEditField
        InfoSagittal                  matlab.ui.control.TextArea
        FrontalViewPanel              matlab.ui.container.Panel
        UIAxesFrontalImg1             matlab.ui.control.UIAxes
        UIAxesFrontalImg2             matlab.ui.control.UIAxes
        UIAxesFrontalFuse             matlab.ui.control.UIAxes
        SliceEditFieldLabel_3         matlab.ui.control.Label
        SliceFrontalField             matlab.ui.control.NumericEditField
        InfoFrontal                   matlab.ui.control.TextArea
        TransverseViewPanel           matlab.ui.container.Panel
        UIAxesTransImg1               matlab.ui.control.UIAxes
        UIAxesTransImg2               matlab.ui.control.UIAxes
        UIAxesTransFuse               matlab.ui.control.UIAxes
        SliceEditFieldLabel           matlab.ui.control.Label
        SliceTransField               matlab.ui.control.NumericEditField
        InfoTrans                     matlab.ui.control.TextArea
    end


    properties (Access = private)
        
         % Image characteristics
         Img1_size              % Size Image 1
         Img2_size              % Size Image 2
         Img1_max               % Maximum Intensity Image 1
         Img2_max               % Maximum Intensity Image 2
         
         % Current slice
         Imgs_sagittal          % Current sagittal slice
         Imgs_transverse        % Current transverse slice
         Imgs_frontal           % Current frontal slice     
        
    end

    properties (Access = public)
        
        Img1                        % Input image 1 TODO change to private?
        Img2                        % Input image 2
        
    end
    
    methods (Access = private)
        
        function plot_images(app,position) %TODO only adjust image that has been adjusted
            
        switch position
            case 'transverse'
                
                % Image 1
                imshow(squeeze(app.Img1(:,app.Imgs_transverse,:)),[0 app.Img1_max],'Parent',app.UIAxesTransImg1,'Interpolation','bilinear','InitialMagnification', 'fit');
                % Image 2
                imshow(squeeze(app.Img2(:,app.Imgs_transverse,:)),[0 app.Img2_max],'Parent',app.UIAxesTransImg2,'Interpolation','bilinear', 'InitialMagnification', 'fit');
                % Fuse
                imshowpair(squeeze(app.Img1(:,app.Imgs_transverse,:)),squeeze(app.Img2(:,app.Imgs_transverse,:)),'Parent',app.UIAxesTransFuse); % TODO spatial referencing object toevoegen???
                
            case 'sagittal'
                
                % Image 1
                imshow(squeeze(app.Img1(app.Imgs_sagittal,:,:)),[0 app.Img1_max],'Parent',app.UIAxesSagittalImg1,'Interpolation','bilinear', 'InitialMagnification', 'fit');
                % Image 2
                imshow(squeeze(app.Img2(app.Imgs_sagittal,:,:)),[0 app.Img2_max],'Parent',app.UIAxesSagittalImg2,'Interpolation','bilinear', 'InitialMagnification', 'fit');
                % Fuse
                imshowpair(squeeze(app.Img1(app.Imgs_sagittal,:,:)),squeeze(app.Img2(app.Imgs_sagittal,:,:)),'Parent',app.UIAxesSagittalFuse);
                
            case 'frontal'
                
                % Image 1
                imshow(squeeze(app.Img1(:,:,app.Imgs_frontal)),[0 app.Img1_max],'Parent',app.UIAxesFrontalImg1,'Interpolation','bilinear', 'InitialMagnification', 'fit');
                % Image 2
                imshow(squeeze(app.Img2(:,:,app.Imgs_frontal)),[0 app.Img2_max],'Parent',app.UIAxesFrontalImg2,'Interpolation','bilinear', 'InitialMagnification', 'fit');
                % Fuse
                imshowpair(squeeze(app.Img1(:,:,app.Imgs_frontal)),squeeze(app.Img2(:,:,app.Imgs_frontal)),'Parent',app.UIAxesFrontalFuse);
                
            otherwise
                
                e = errordlg('Error: CompImages something wrong in plot_images function.');
                
        end

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Start(app, Img1, Img2)
        
            % Adjust appearance
            drawnow;
            app.ImageViewerSamDoncheUIFigure.WindowState = 'maximized'; % Maximize window            
            
            if nargin == 0
                
                w = warndlg('Warning: CompImages has no input variables (app). Check.');
                
            elseif nargin == 1
                
                msgbox('No images were given as input. Load images with menu.','WindowStyle','modal')
                
            elseif nargin == 2
                
                A = 2
                
            elseif nargin == 3
                          
                % Change type of image matrix to double
                app.Img1 = double(Img1);
                app.Img2 = double(Img2);
                
                % Image characteristics (X = sagittal, Y = transverse, Z = frontal)
                app.Img1_max    = max(max(max(app.Img1)));
                app.Img2_max    = max(max(max(app.Img2)));
                app.Img1_size   = size(app.Img1);
                app.Img2_size   = size(app.Img2);
                
                % Start slices based on Image 2
                linco = find(app.Img2);                                 % Linear coordinates of non-zero elements
                [Boxx, Boxy, Boxz]  = ind2sub(size(app.Img2),linco);    % Linear coordinates to indexes
                
                range_x             = unique(Boxx);                     % Range x (Sagittal) of MRI, in these frames are images
                range_x_min         = min(Boxx);                        % TODO min/max waarden niet nodig?
                range_x_max         = max(Boxx);
                range_y             = unique(Boxy);                     % Range y (Transverse) of MRI
                range_y_min         = min(Boxy);
                range_y_max         = max(Boxy);
                range_z             = unique(Boxz);                     % Range x (Frontal) of MRI
                range_z_min         = min(Boxz);
                range_z_max         = max(Boxz);
                
                app.Imgs_sagittal       = round(median(range_x));       % Start slice sagittal; telkens in midden van de range
                app.Imgs_transverse     = round(median(range_y));       % Start slice transverse
                app.Imgs_frontal        = round(median(range_z));       % Start slice frontal
                
                % Start images
                % Image 1
                imshow(squeeze(app.Img1(:,app.Imgs_transverse,:)),[0 app.Img1_max],'Parent',app.UIAxesTransImg1,'Interpolation','bilinear','InitialMagnification','fit');
                imshow(squeeze(app.Img1(:,:,app.Imgs_frontal)),[0 app.Img1_max],'Parent',app.UIAxesFrontalImg1,'Interpolation','bilinear','InitialMagnification','fit');
                imshow(squeeze(app.Img1(app.Imgs_sagittal,:,:)),[0 app.Img1_max],'Parent',app.UIAxesSagittalImg1,'Interpolation','bilinear', 'InitialMagnification', 'fit');
                
                % Image 2
                imshow(squeeze(app.Img2(:,app.Imgs_transverse,:)),[0 app.Img2_max],'Parent',app.UIAxesTransImg2,'Interpolation','bilinear', 'InitialMagnification', 'fit');
                imshow(squeeze(app.Img2(:,:,app.Imgs_frontal)),[0 app.Img2_max],'Parent',app.UIAxesFrontalImg2,'Interpolation','bilinear', 'InitialMagnification', 'fit');
                imshow(squeeze(app.Img2(app.Imgs_sagittal,:,:)),[0 app.Img2_max],'Parent',app.UIAxesSagittalImg2,'Interpolation','bilinear', 'InitialMagnification', 'fit');
                
                % Fuse
                imshowpair(squeeze(app.Img1(:,app.Imgs_transverse,:)),squeeze(app.Img2(:,app.Imgs_transverse,:)),'Parent',app.UIAxesTransFuse);
                imshowpair(squeeze(app.Img1(:,:,app.Imgs_frontal)),squeeze(app.Img2(:,:,app.Imgs_frontal)),'Parent',app.UIAxesFrontalFuse);
                imshowpair(squeeze(app.Img1(app.Imgs_sagittal,:,:)),squeeze(app.Img2(app.Imgs_sagittal,:,:)),'Parent',app.UIAxesSagittalFuse);
                
                % Start fields
                % Transverse
                app.SliceTransField.Value = app.Imgs_transverse;
                app.InfoTrans.Value = 'Information';
                app.InfoTrans.Value = [app.InfoTrans.Value; strcat('Total number of slices:',{' '},num2str(app.Img1_size(2)))];
                app.InfoTrans.Value = [app.InfoTrans.Value; strcat('Range:',{' '},num2str(range_y_min),{' '},'-',{' '},num2str(range_y_max))]; % TODO richtingen bij vermelden vb ventraal - dorsaal, anterior - posterior, links - rechts
            
                % Sagittal
                app.SliceSagittalField.Value = app.Imgs_sagittal;
                app.InfoSagittal.Value = 'Information';
                app.InfoSagittal.Value = [app.InfoSagittal.Value; strcat('Total number of slices:',{' '},num2str(app.Img1_size(1)))];
                app.InfoSagittal.Value = [app.InfoSagittal.Value; strcat('Range:',{' '},num2str(range_x_min),{' '},'-',{' '},num2str(range_x_max))];
                
                % Frontal
                app.SliceFrontalField.Value = app.Imgs_frontal;
                app.InfoFrontal.Value = 'Information';
                app.InfoFrontal.Value = [app.InfoFrontal.Value; strcat('Total number of slices:',{' '},num2str(app.Img1_size(3)))];
                app.InfoFrontal.Value = [app.InfoFrontal.Value; strcat('Range:',{' '},num2str(range_z_min),{' '},'-',{' '},num2str(range_z_max))];
                
            else
                
                e = errordlg('Error: CompImg has too many input arguments!')
                
            end

        end

        % Value changed function: SliceTransField
        function SliceTransFieldValueChanged(app, event)
            app.Imgs_transverse = app.SliceTransField.Value;
            plot_images(app,'transverse');
        end

        % Value changed function: SliceSagittalField
        function SliceSagittalFieldValueChanged(app, event)
            app.Imgs_sagittal = app.SliceSagittalField.Value;
            plot_images(app,'sagittal');
        end

        % Value changed function: SliceFrontalField
        function SliceFrontalFieldValueChanged(app, event)
            app.Imgs_frontal = app.SliceFrontalField.Value;
            plot_images(app,'frontal');
        end

        % Key press function: ImageViewerSamDoncheUIFigure
        function ViewerControls(app, event)
            key = event.Key;
            if      strcmp(key,'a')
                    app.Imgs_transverse = app.Imgs_transverse - 1;      % Move transverse view to ... TODO                
                    plot_images(app,'transverse');
                    app.SliceTransField.Value = app.Imgs_transverse;
                    
            elseif  strcmp(key,'z')
                    app.Imgs_transverse = app.Imgs_transverse + 1;      % Move transverse view to ...
                    plot_images(app,'transverse');
                    app.SliceTransField.Value = app.Imgs_transverse;
                    
            elseif  strcmp(key,'q')
                    app.Imgs_sagittal = app.Imgs_sagittal - 1;          % Move sagittal view to ...    
                    plot_images(app,'sagittal');
                    app.SliceSagittalField.Value = app.Imgs_sagittal;

            elseif  strcmp(key,'s')
                    app.Imgs_sagittal = app.Imgs_sagittal + 1;          % Move sagittal view to ...    
                    plot_images(app,'sagittal');
                    app.SliceSagittalField.Value = app.Imgs_sagittal;                        
                
            elseif  strcmp(key,'w')
                    app.Imgs_frontal = app.Imgs_frontal - 1;
                    plot_images(app,'frontal');
                    app.SliceFrontalField.Value = app.Imgs_frontal;
                
            elseif  strcmp(key,'x')
                    app.Imgs_frontal = app.Imgs_frontal + 1;
                    plot_images(app,'frontal');
                    app.SliceFrontalField.Value = app.Imgs_frontal;                        

            else
%                     w = 'Error!'; TODO aanpassen
%                     w = [w; strcat('Use:')];
%                     w = [w; strcat('- Transverse: A/Z')];
%                     w = [w; strcat('- Sagittal: Q/S')];
%                     w = [w; strcat('- Frontal: W/X')];
%                     
%                     w = warndlg(w);
            end
            
        end

        % Menu selected function: LoadImage2Menu
        function LoadImage2MenuSelected(app, event)
%             
%             [FILENAME, PATHNAME, FILTERINDEX] = uigetfile('*.nii');
%             
%             if      filterindex == 1    % A file was selected
%                 
%                 MRI = niftiread(fullfile([PATHNAME,FILENAME]));
%                 MRI_info = niftiinfo(fullfile([PATHNAME,FILENAME]));
%                 
%             elseif  filterindex == 0    % No file was selected
%                 
%                 app.Error.Value = 'Error!';
%                 app.Error.Value = [app.Error.Value; 'No file was selected'];                
%                 
%             else
%                 
%                 app.Error.Value = 'Unknown error!';
%                 app.Error.Value = [app.Error.Value; 'Something went wrong in LoadMRIMenu function'];  
%                 
%             end
%             
%             %% TODO plot images after load
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageViewerSamDoncheUIFigure = uifigure('Visible', 'off');
            app.ImageViewerSamDoncheUIFigure.Name = 'Image Viewer - Sam Donche';
            app.ImageViewerSamDoncheUIFigure.KeyPressFcn = createCallbackFcn(app, @ViewerControls, true);
            app.FileMenu = uimenu(app.ImageViewerSamDoncheUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadImage1REFMenu = uimenu(app.FileMenu);
            app.LoadImage1REFMenu.Text = 'Load Image 1 (REF)';
            app.LoadImage2Menu = uimenu(app.FileMenu);
            app.LoadImage2Menu.MenuSelectedFcn = createCallbackFcn(app, @LoadImage2MenuSelected, true);
            app.LoadImage2Menu.Text = 'Load Image 2';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Text = 'Save';
            app.SagittalViewPanel = uipanel(app.ImageViewerSamDoncheUIFigure);
            app.SagittalViewPanel.Title = 'Sagittal View';
            app.UIAxesSagittalImg1 = uiaxes(app.SagittalViewPanel);
            title(app.UIAxesSagittalImg1, '')
            xlabel(app.UIAxesSagittalImg1, '')
            ylabel(app.UIAxesSagittalImg1, '')
            app.UIAxesSagittalImg2 = uiaxes(app.SagittalViewPanel);
            title(app.UIAxesSagittalImg2, '')
            xlabel(app.UIAxesSagittalImg2, '')
            ylabel(app.UIAxesSagittalImg2, '')
            app.UIAxesSagittalFuse = uiaxes(app.SagittalViewPanel);
            title(app.UIAxesSagittalFuse, '')
            xlabel(app.UIAxesSagittalFuse, '')
            ylabel(app.UIAxesSagittalFuse, '')
            app.SliceEditFieldLabel_2 = uilabel(app.SagittalViewPanel);
            app.SliceEditFieldLabel_2.Text = 'Slice';
            app.SliceSagittalField = uieditfield(app.SagittalViewPanel, 'numeric');
            app.SliceSagittalField.ValueChangedFcn = createCallbackFcn(app, @SliceSagittalFieldValueChanged, true);
            app.InfoSagittal = uitextarea(app.SagittalViewPanel);
            app.InfoSagittal.Editable = 'off';
            app.InfoSagittal.Enable = 'off';
            app.FrontalViewPanel = uipanel(app.ImageViewerSamDoncheUIFigure);
            app.FrontalViewPanel.Title = 'Frontal View';
            app.UIAxesFrontalImg1 = uiaxes(app.FrontalViewPanel);
            title(app.UIAxesFrontalImg1, '')
            xlabel(app.UIAxesFrontalImg1, '')
            ylabel(app.UIAxesFrontalImg1, '')
            app.UIAxesFrontalImg2 = uiaxes(app.FrontalViewPanel);
            title(app.UIAxesFrontalImg2, '')
            xlabel(app.UIAxesFrontalImg2, '')
            ylabel(app.UIAxesFrontalImg2, '')
            app.UIAxesFrontalFuse = uiaxes(app.FrontalViewPanel);
            title(app.UIAxesFrontalFuse, '')
            xlabel(app.UIAxesFrontalFuse, '')
            ylabel(app.UIAxesFrontalFuse, '')
            app.SliceEditFieldLabel_3 = uilabel(app.FrontalViewPanel);
            app.SliceEditFieldLabel_3.Text = 'Slice';
            app.SliceFrontalField = uieditfield(app.FrontalViewPanel, 'numeric');
            app.SliceFrontalField.ValueChangedFcn = createCallbackFcn(app, @SliceFrontalFieldValueChanged, true);
            app.InfoFrontal = uitextarea(app.FrontalViewPanel);
            app.InfoFrontal.Editable = 'off';
            app.InfoFrontal.Enable = 'off';
            app.TransverseViewPanel = uipanel(app.ImageViewerSamDoncheUIFigure);
            app.TransverseViewPanel.Title = 'Transverse View';
            app.UIAxesTransImg1 = uiaxes(app.TransverseViewPanel);
            title(app.UIAxesTransImg1, '')
            xlabel(app.UIAxesTransImg1, '')
            ylabel(app.UIAxesTransImg1, '')
            app.UIAxesTransImg2 = uiaxes(app.TransverseViewPanel);
            title(app.UIAxesTransImg2, '')
            xlabel(app.UIAxesTransImg2, '')
            ylabel(app.UIAxesTransImg2, '')
            app.UIAxesTransFuse = uiaxes(app.TransverseViewPanel);
            title(app.UIAxesTransFuse, '')
            xlabel(app.UIAxesTransFuse, '')
            ylabel(app.UIAxesTransFuse, '')
            app.SliceEditFieldLabel = uilabel(app.TransverseViewPanel);
            app.SliceEditFieldLabel.Text = 'Slice';
            app.SliceTransField = uieditfield(app.TransverseViewPanel, 'numeric');
            app.SliceTransField.ValueChangedFcn = createCallbackFcn(app, @SliceTransFieldValueChanged, true);
            app.InfoTrans = uitextarea(app.TransverseViewPanel);
            app.InfoTrans.Editable = 'off';
            app.InfoTrans.Enable = 'off';
            % Show the figure after all components are created
            app.ImageViewerSamDoncheUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CompImages(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageViewerSamDoncheUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)Start(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageViewerSamDoncheUIFigure)
        end
    end
end