classdef DosePainting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DosePaintingSamDoncheUIFigure  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadCTMenu                     matlab.ui.container.Menu
        LoadMRIMenu                    matlab.ui.container.Menu
        LoadPETMenu                    matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        ViewCTMenu                     matlab.ui.container.Menu
        ViewMRIMenu                    matlab.ui.container.Menu
        ViewPETMenu                    matlab.ui.container.Menu
        ViewCTMRIMenu                  matlab.ui.container.Menu
        ViewCTPETMenu                  matlab.ui.container.Menu
        BoundingBoxMenu                matlab.ui.container.Menu
        DrawBoxMenu                    matlab.ui.container.Menu
        DrawBoxTransverseViewMenu      matlab.ui.container.Menu
        DrawBoxSagittalViewMenu        matlab.ui.container.Menu
        DrawBoxFrontalViewMenu         matlab.ui.container.Menu
        SaveBoxMenu                    matlab.ui.container.Menu
        SaveBoxTransverseMenu          matlab.ui.container.Menu
        SaveBoxSagittalMenu            matlab.ui.container.Menu
        SaveBoxFrontalMenu             matlab.ui.container.Menu
        FinaliseBoxMenu                matlab.ui.container.Menu
        ClearMenu                      matlab.ui.container.Menu
        IsocenterMenu                  matlab.ui.container.Menu
        IsocenterMRIMenu               matlab.ui.container.Menu
        IsocenterPETMenu               matlab.ui.container.Menu
        ErrorPanel                     matlab.ui.container.Panel
        Label_2                        matlab.ui.control.Label
        Error                          matlab.ui.control.TextArea
        InfoTransverseView             matlab.ui.container.Panel
        SliceEditFieldLabel            matlab.ui.control.Label
        SliceTransField                matlab.ui.control.NumericEditField
        InfoTransMRI                   matlab.ui.control.TextArea
        InfoTransPET                   matlab.ui.control.TextArea
        InfoTransCT                    matlab.ui.control.TextArea
        TransverseViewPanel            matlab.ui.container.Panel
        UIAxesTrans                    matlab.ui.control.UIAxes
        SagittalViewPanel              matlab.ui.container.Panel
        UIAxesSagittal                 matlab.ui.control.UIAxes
        FrontalViewPanel               matlab.ui.container.Panel
        UIAxesFrontal                  matlab.ui.control.UIAxes
        InfoSagittalView               matlab.ui.container.Panel
        SliceEditFieldLabel_2          matlab.ui.control.Label
        SliceSagittalField             matlab.ui.control.NumericEditField
        InfoSagittalMRI                matlab.ui.control.TextArea
        InfoSagittalPET                matlab.ui.control.TextArea
        InfoSagittalCT                 matlab.ui.control.TextArea
        InfoFrontalView                matlab.ui.container.Panel
        SliceEditFieldLabel_3          matlab.ui.control.Label
        SliceFrontalField              matlab.ui.control.NumericEditField
        InfoFrontalMRI                 matlab.ui.control.TextArea
        InfoFrontalPET                 matlab.ui.control.TextArea
        InfoFrontalCT                  matlab.ui.control.TextArea
    end


    properties (Access = private)
        
        % Image characteristics
        CT_size
        MRI_size
        PET_size
        CT_max
        MRI_max
        PET_max
        
%         size_Img                % Image Size
%         Img_max                 % Maximum intensity image
%         size_Sagittal           % Image Saggital Size
%         size_Transverse         % Image Transverse Size
%         size_Frontal            % Image Frontal Size
        
        % Current slice/modality
        slice_Sagittal          % Current sagittal slice
        slice_Transverse        % Current transverse slice
        slice_Frontal           % Current Frontal slice
        current_modality        % Current modality in viewer
        
        % Bounding Box
        MRI_ROI_SEG_LARG;
        % box_trans ...
        
    end

    properties (Access = public)
        
        CT                      % CT image
        CT_info                 % CT metadata
        MRI                     % MRI image
        MRI_info                % MRI metadata
        PET                     % PET image
        PET_info                % PET metadata
        box_trans               % TODO change to private
        mask_trans              % TODO change to private
        box_sagittal
        mask_sagittal
        box_frontal
        mask_frontal
        bounding_box
        SegmMRI                 % Informatie T1ce region
        SegmPET                 % Informatie PET region
        
    end
    
    methods (Access = private)
        
        function plot_images(app,modality) %TODO only adjust image that has been adjusted
        % Plot the images of the desired imaging modality

            switch modality
                case 'CT'
                    
                    imshow(squeeze(app.CT(:,app.slice_Transverse,:)),[0.25*app.CT_max 0.5*app.CT_max],'Parent',app.UIAxesTrans,'Interpolation','bilinear','InitialMagnification','fit'); % TODO display range aanpasssen naargelang modaliteit
                    imshow(squeeze(app.CT(:,:,app.slice_Frontal)),[0.25*app.CT_max 0.5*app.CT_max],'Parent',app.UIAxesFrontal,'Interpolation','bilinear','InitialMagnification','fit');
                    imshow(squeeze(app.CT(app.slice_Sagittal,:,:)),[0.25*app.CT_max 0.5*app.CT_max],'Parent',app.UIAxesSagittal,'Interpolation','bilinear','InitialMagnification','fit');                     
                    
                case 'MRI'
                    
                    imshow(squeeze(app.MRI(:,app.slice_Transverse,:)),[0 app.MRI_max],'Parent',app.UIAxesTrans,'Interpolation','bilinear','InitialMagnification','fit'); % TODO display range aanpasssen naargelang modaliteit
                    imshow(squeeze(app.MRI(:,:,app.slice_Frontal)),[0 app.MRI_max],'Parent',app.UIAxesFrontal,'Interpolation','bilinear','InitialMagnification','fit');
                    imshow(squeeze(app.MRI(app.slice_Sagittal,:,:)),[0 app.MRI_max],'Parent',app.UIAxesSagittal,'Interpolation','bilinear','InitialMagnification','fit'); 
                    
                case 'PET'
                    
                    imshow(squeeze(app.PET(:,app.slice_Transverse,:)),[0 app.PET_max],'Parent',app.UIAxesTrans,'Interpolation','bilinear','InitialMagnification','fit'); % TODO display range aanpasssen naargelang modaliteit
                    imshow(squeeze(app.PET(:,:,app.slice_Frontal)),[0 app.PET_max],'Parent',app.UIAxesFrontal,'Interpolation','bilinear','InitialMagnification','fit');
                    imshow(squeeze(app.PET(app.slice_Sagittal,:,:)),[0 app.PET_max],'Parent',app.UIAxesSagittal,'Interpolation','bilinear','InitialMagnification','fit');   
                    
                otherwise
                    
                    % TODO insert error here   
                    
            end

            
        end
        %TODO functie die de dimensies van de verschillende modaliteiten
        %checkt
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Start(app, Img)

%                 screenSize = get(groot,'ScreenSize');
%                 screenWidth = screenSize(3);
%                 screenHeight = screenSize(4);
%                 left = screenWidth*0.01;
%                 bottom = screenHeight*0.01;
%                 width = screenWidth*0.99;
%                 height = screenHeight*0.99;
%                 drawnow;
%                 app.DosePaintingSamDoncheUIFigure.Position = [left bottom width height]; 
            
            % localX= evalin('base','X'); to read in variables from
            % workspace; sliceViewer?
            % Adjust appearance
                drawnow;
                app.DosePaintingSamDoncheUIFigure.WindowState = 'maximized'; % Maximize window, TODO make this fullscreen and make button to leave application

        end

        % Menu selected function: LoadCTMenu
        function LoadCT(app, event)
            
            [filename, pathname, filterindex] = uigetfile('*.nii');
            
            if      filterindex == 1    % A file was selected
                
                app.CT = niftiread(fullfile([pathname,filename]));          % read
                app.CT = double(app.CT);                                    % Convert to double
                app.CT_info = niftiinfo(fullfile([pathname,filename]));
                
            elseif  filterindex == 0    % No file was selected
                
                app.Error.Value = 'Error!';
                app.Error.Value = [app.Error.Value; 'No file was selected'];                
                
            else
                
                app.Error.Value = 'Unknown error!'; % TODO eventueel deze error verwijderen?
                app.Error.Value = [app.Error.Value; 'Something went wrong in LoadCT function'];  
                
            end

            % Return app window to foreground again
            drawnow;
            figure(app.DosePaintingSamDoncheUIFigure);
            
            % CT Characteristics
            app.CT_size = size(app.CT);                             % CT size; X = sagittal, Y = transverse, Z = frontal
            app.CT_max = max(max(max(app.CT)));                     % CT maximum (overall image)
            
            % Start slices
            app.slice_Sagittal      = round(app.CT_size(1)/2);      % The range is not calculated for CT, the slices are just put in the middle
            app.slice_Transverse    = round(app.CT_size(2)/2);
            app.slice_Frontal       = round(app.CT_size(3)/2);
            
            % plot CT
            app.current_modality = 'CT';
            plot_images(app,app.current_modality);
            
            % Start Fields; no info field for CT; TODO add info field with
            % overall size image?
            % Transverse
            app.SliceTransField.Value = app.slice_Transverse;
            app.InfoTransCT.Value = strcat('CT: ',{' '},'Total number of slices =',{' '},num2str(app.CT_size(2)));
            
            % Sagittal
            app.SliceSagittalField.Value = app.slice_Sagittal;
            app.InfoSagittalCT.Value = strcat('CT: ',{' '},'Total number of slices =',{' '},num2str(app.CT_size(1)));
            
            % Frontal
            app.SliceFrontalField.Value = app.slice_Frontal;
            app.InfoFrontalCT.Value = strcat('CT: ',{' '},'Total number of slices =',{' '},num2str(app.CT_size(3)));
            
        end

        % Menu selected function: LoadMRIMenu
        function LoadMRI(app, event)
                        
            [filename, pathname, filterindex] = uigetfile('*.nii');
            
            if      filterindex == 1    % A file was selected
                
                app.MRI = niftiread(fullfile([pathname,filename]));         % read
                app.MRI = double(app.MRI);                                  % Convert to double
                app.MRI_info = niftiinfo(fullfile([pathname,filename]));
                
            elseif  filterindex == 0    % No file was selected
                
                app.Error.Value = 'Error!';
                app.Error.Value = [app.Error.Value; 'No file was selected'];                
                
            else
                
                app.Error.Value = 'Unknown error!';
                app.Error.Value = [app.Error.Value; 'Something went wrong in LoadMRI function'];  
                
            end

            % Return app window to foreground again
            drawnow;
            figure(app.DosePaintingSamDoncheUIFigure)
            
            % MRI Characteristics
            app.MRI_size = size(app.MRI);                       % MRI size; X = sagittal, Y = transverse, Z = frontal
            app.MRI_max = max(max(max(app.MRI)));               % MRI maximum (overall image)
            
            % Start slices
            linco = find(app.MRI);                              % Linear coordinates of non-zero elements
            [MRI_Boxx, MRI_Boxy, MRI_Boxz]  = ind2sub(size(app.MRI),linco); % Linear coordinates to indexes
            
            MRI_range_x             = unique(MRI_Boxx);         % Range MRI x (Sagittal), in these frames non-zero frames pixels are found
            MRI_range_x_min         = min(MRI_Boxx);            % Range MRI x minimum        
            MRI_range_x_max         = max(MRI_Boxx);            % Range MRI x maximum
            MRI_range_y             = unique(MRI_Boxy);         % Range MRI y (Transverse)
            MRI_range_y_min         = min(MRI_Boxy);            % Range MRI y minimum
            MRI_range_y_max         = max(MRI_Boxy);            % Range MRI y maximum
            MRI_range_z             = unique(MRI_Boxz);         % Range MRI z (Frontal)
            MRI_range_z_min         = min(MRI_Boxz);            % Range MRI z minimum
            MRI_range_z_max         = max(MRI_Boxz);            % Range MRI z maximum
            
            app.slice_Sagittal      = round(median(MRI_range_x));   % Start slice sagittal
            app.slice_Transverse    = round(median(MRI_range_y));   % Start slice transverse
            app.slice_Frontal       = round(median(MRI_range_z));   % Start slice frontal
            
            % plot MRI
            app.current_modality = 'MRI';
            plot_images(app,app.current_modality);

            % Start Fields
            % Transverse
            app.SliceTransField.Value = app.slice_Transverse;
            app.InfoTransMRI.Value = strcat('MRI: ',{' '},'Total number of slices =',{' '},num2str(app.MRI_size(2)));
            app.InfoTransMRI.Value = [app.InfoTransMRI.Value; strcat('MRI: ',{' '},'Range =',{' '},num2str(MRI_range_y_min),{' '},'-',{' '},num2str(MRI_range_y_max))]; % TODO richtingen bij vermelden vb ventraal - dorsaal, anterior - posterior, links - rechts
            
            % Sagittal
            app.SliceSagittalField.Value = app.slice_Sagittal;
            app.InfoSagittalMRI.Value = strcat('MRI: ',{' '},'Total number of slices =',{' '},num2str(app.MRI_size(1)));
            app.InfoSagittalMRI.Value = [app.InfoSagittalMRI.Value; strcat('MRI: ',{' '},'Range =',{' '},num2str(MRI_range_x_min),{' '},'-',{' '},num2str(MRI_range_x_max))];
            
            % Frontal
            app.SliceFrontalField.Value = app.slice_Frontal;
            app.InfoFrontalMRI.Value = strcat('MRI: ',{' '},'Total number of slices =',{' '},num2str(app.MRI_size(3)));
            app.InfoFrontalMRI.Value = [app.InfoFrontalMRI.Value; strcat('MRI:','Range =',{' '},num2str(MRI_range_z_min),{' '},'-',{' '},num2str(MRI_range_z_max))];
            
        end

        % Menu selected function: LoadPETMenu
        function LoadPET(app, event)
            
            [filename, pathname, filterindex] = uigetfile('*.nii');
            
            if      filterindex == 1    % A file was selected
                
                app.PET = niftiread(fullfile([pathname,filename]));         % read
                app.PET = double(app.PET);                                  % Convert to double
                app.PET_info = niftiinfo(fullfile([pathname,filename]));
                
            elseif  filterindex == 0    % No file was selected
                
                app.Error.Value = 'Error!';
                app.Error.Value = [app.Error.Value; 'No file was selected'];                
                
            else
                
                app.Error.Value = 'Unknown error!';
                app.Error.Value = [app.Error.Value; 'Something went wrong in LoadPET function'];  
                
            end

            % Return app window to foreground again
            drawnow;
            figure(app.DosePaintingSamDoncheUIFigure)
            
            % PET Characteristics
            app.PET_size = size(app.PET);                       % PET size; X = sagittal, Y = transverse, Z = frontal
            app.PET_max = max(max(max(app.PET)));               % PET maximum (overall image)
            
            % Start slices
            linco = find(app.PET);                              % Linear coordinates of non-zero elements
            [PET_Boxx, PET_Boxy, PET_Boxz]  = ind2sub(size(app.PET),linco); % Linear coordinates to indexes
            
            PET_range_x             = unique(PET_Boxx);         % Range PET x (Sagittal), in these frames non-zero frames pixels are found
            PET_range_x_min         = min(PET_Boxx);            % Range PET x minimum        
            PET_range_x_max         = max(PET_Boxx);            % Range PET x maximum
            PET_range_y             = unique(PET_Boxy);         % Range PET y (Transverse)
            PET_range_y_min         = min(PET_Boxy);            % Range PET y minimum
            PET_range_y_max         = max(PET_Boxy);            % Range PET y maximum
            PET_range_z             = unique(PET_Boxz);         % Range PET z (Frontal)
            PET_range_z_min         = min(PET_Boxz);            % Range PET z minimum
            PET_range_z_max         = max(PET_Boxz);            % Range PET z maximum
            
            app.slice_Sagittal      = round(median(PET_range_x));   % Start slice sagittal
            app.slice_Transverse    = round(median(PET_range_y));   % Start slice transverse
            app.slice_Frontal       = round(median(PET_range_z));   % Start slice frontal
            
            % plot PET
            app.current_modality = 'PET';
            plot_images(app,app.current_modality);

            % Start Fields
            % Transverse
            app.SliceTransField.Value = app.slice_Transverse;
            app.InfoTransPET.Value = strcat('PET: ',{' '},'Total number of slices =',{' '},num2str(app.PET_size(2)));
            app.InfoTransPET.Value = [app.InfoTransPET.Value; strcat('PET: ',{' '},'Range =',{' '},num2str(PET_range_y_min),{' '},'-',{' '},num2str(PET_range_y_max))]; % TODO richtingen bij vermelden vb ventraal - dorsaal, anterior - posterior, links - rechts
            
            % Sagittal
            app.SliceSagittalField.Value = app.slice_Sagittal;
            app.InfoSagittalPET.Value = strcat('PET: ',{' '},'Total number of slices =',{' '},num2str(app.PET_size(1)));
            app.InfoSagittalPET.Value = [app.InfoSagittalPET.Value; strcat('PET: ',{' '},'Range =',{' '},num2str(PET_range_x_min),{' '},'-',{' '},num2str(PET_range_x_max))];
            
            % Frontal
            app.SliceFrontalField.Value = app.slice_Frontal;
            app.InfoFrontalPET.Value = strcat('PET: ',{' '},'Total number of slices =',{' '},num2str(app.PET_size(3)));
            app.InfoFrontalPET.Value = [app.InfoFrontalPET.Value; strcat('PET:','Range =',{' '},num2str(PET_range_z_min),{' '},'-',{' '},num2str(PET_range_z_max))];
            
        end

        % Menu selected function: ViewCTMenu
        function ViewCT(app, event)
            
            app.current_modality = 'CT';
            plot_images(app,app.current_modality);
            
        end

        % Menu selected function: ViewMRIMenu
        function ViewMRI(app, event)
           
            app.current_modality = 'MRI';
            plot_images(app,app.current_modality);
            
        end

        % Menu selected function: ViewPETMenu
        function ViewPET(app, event)
            
            app.current_modality = 'PET';
            plot_images(app,app.current_modality);
            
        end

        % Key press function: DosePaintingSamDoncheUIFigure
        function ViewerControls(app, event)
            key = event.Key;
            if      strcmp(key,'a')
                        app.slice_Transverse = app.slice_Transverse - 1;    % Move transverse view towards superior
                        plot_images(app,app.current_modality);
                        app.SliceTransField.Value = app.slice_Transverse;
            elseif  strcmp(key,'z')
                        app.slice_Transverse = app.slice_Transverse + 1;    % Move transverse view towards inferior
                        plot_images(app,app.current_modality);
                        app.SliceTransField.Value = app.slice_Transverse;
            elseif  strcmp(key,'q')
                        app.slice_Sagittal = app.slice_Sagittal - 1;    % Move sagittal view towards rights
                        plot_images(app,app.current_modality);
                        app.SliceSagittalField.Value = app.slice_Sagittal;
            elseif  strcmp(key,'s')
                        app.slice_Sagittal = app.slice_Sagittal + 1;    % Move sagittal view towards left
                        plot_images(app,app.current_modality);
                        app.SliceSagittalField.Value = app.slice_Sagittal;
            elseif  strcmp(key,'w')
                        app.slice_Frontal = app.slice_Frontal - 1;    % Move frontal view towards dorsal
                        plot_images(app,app.current_modality);
                        app.SliceFrontalField.Value = app.slice_Frontal;
            elseif  strcmp(key,'x')
                        app.slice_Frontal = app.slice_Frontal + 1;    % Move frontal view towards ventral
                        plot_images(app,app.current_modality);
                        app.SliceFrontalField.Value = app.slice_Frontal;
            else
                    app.Error.Value = 'Use';
                    app.Error.Value = [app.Error.Value; strcat('- Transverse:')];
                    app.Error.Value = [app.Error.Value; strcat({'       '},'A - towards superior')];
                    app.Error.Value = [app.Error.Value; strcat({'       '},'Z - towards inferior')];
                    app.Error.Value = [app.Error.Value; strcat('- Sagittal:')];
                    app.Error.Value = [app.Error.Value; strcat({'       '},'Q - towards right')];
                    app.Error.Value = [app.Error.Value; strcat({'       '},'S - towards left')];
                    app.Error.Value = [app.Error.Value; strcat('- Frontal:')];
                    app.Error.Value = [app.Error.Value; strcat({'       '},'W - towards dorsal')];
                    app.Error.Value = [app.Error.Value; strcat({'       '},'X - towards ventral')];
            end
            
        end

        % Value changed function: SliceTransField
        function TransSliceFieldValue(app, event)
            
            app.slice_Transverse = app.SliceTransField.Value;
            plot_images(app,app.current_modality);
            
        end

        % Value changed function: SliceSagittalField
        function SagittalSliceFieldValue(app, event)
            
            app.slice_Sagittal = app.SliceSagittalField.Value;
            plot_images(app,app.current_modality);
            
        end

        % Value changed function: SliceFrontalField
        function FrontalSliceFieldValue(app, event)
            
            app.slice_Frontal = app.SliceFrontalField.Value;
            plot_images(app,app.current_modality);
            
        end

        % Menu selected function: DrawBoxTransverseViewMenu
        function DrawBoxTrans(app, event)
            
            app.box_trans = drawcircle('Parent',app.UIAxesTrans,'Center', [100 100], 'Radius', ceil(12/(2*0.275)), 'InteractionsAllowed','translate','Color', [0.00,0.45,0.74]); % 12mm/0,275 = 43,6364; drawrectangle
            % TODO effect van andere modaliteiten inlezen en dan een box te
            % tekenen?
            
        end

        % Menu selected function: SaveBoxTransverseMenu
        function SaveBoxTrans(app, event)
            
            mask_trans_tmp = double(createMask(app.box_trans));                                 % Create mask from ROI
            app.mask_trans = zeros(app.MRI_size(1),app.MRI_size(2),app.MRI_size(3));               % Initialisation
            
            for i = 1:app.MRI_size(2)
                app.mask_trans(:,i,:) = mask_trans_tmp;                                         % "draw" the ROI over the whole length of the image; the circle ROI results in a cylindrical shape
            end
            %figure()
            %sliceViewer(app.mask_trans)
            %TODO: add check to menu when completed; remove when clear is
            %used
            
        end

        % Menu selected function: DrawBoxSagittalViewMenu
        function DrawBoxSagittal(app, event)
            
            app.box_sagittal = drawcircle('Parent',app.UIAxesSagittal, 'Center', [100 100], 'Radius', ceil(12/(2*0.275)), 'InteractionsAllowed','translate','Color', [0.00,0.45,0.74]); % 12mm/0,275 = 43,6364; drawrectangle
            
        end

        % Menu selected function: SaveBoxSagittalMenu
        function SaveBoxSagittal(app, event)
            
            mask_sagittal_tmp = double(createMask(app.box_sagittal));                           % Create mask from ROI
            app.mask_sagittal = zeros(app.MRI_size(1),app.MRI_size(2),app.MRI_size(3));         % Initialisation
            
            for i = 1:app.MRI_size(1)
                app.mask_sagittal(i,:,:) = mask_sagittal_tmp;                                   % "draw" the ROI over the whole length of the image; the circle ROI results in a cylindrical shape
            end
            %figure()
            %sliceViewer(app.mask_sagittal)
            
        end

        % Menu selected function: DrawBoxFrontalViewMenu
        function DrawBoxFrontal(app, event)
            
            app.box_frontal = drawcircle('Parent',app.UIAxesFrontal, 'Center', [100 100], 'Radius', ceil(12/(2*0.275)), 'InteractionsAllowed','translate','Color', [0.00,0.45,0.74]); % 12mm/0,275 = 43,6364; drawrectangle
            
        end

        % Menu selected function: SaveBoxFrontalMenu
        function SaveBoxFrontal(app, event)

            mask_frontal_tmp = double(createMask(app.box_frontal));                             % Create mask from ROI
            app.mask_frontal = zeros(app.MRI_size(1),app.MRI_size(2),app.MRI_size(3));          % Initialisation
            
            for i = 1:app.MRI_size(3)
                app.mask_frontal(:,:,i) = mask_frontal_tmp;                                     % "draw" the ROI over the whole length of the image; the circle ROI results in a cylindrical shape
            end
            %figure()
            %sliceViewer(app.mask_frontal)
            
        end

        % Menu selected function: FinaliseBoxMenu
        function FinaliseBox(app, event)
            
            empty_trans     = isempty(app.mask_trans);        % isempty(); 1/true = empty, 0/false = not empty
            empty_sagittal  = isempty(app.mask_sagittal);
            empty_frontal   = isempty(app.mask_frontal);
            
            if      empty_trans == false && empty_sagittal == false && empty_frontal == false   % Trans not empty, sagittal not empty, frontal not empty
                
                app.bounding_box = app.mask_trans .* app.mask_sagittal .* app.mask_frontal;     % Funnel different masks into one 'bounding box'
                
                switch app.current_modality
                    case 'CT'
                        
                        new_img = app.CT .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off'             % TODO waarom werkt dit niet?
                        title('Preview Extracted ROI - CT','Color',[0.00,0.45,0.74]);       % TODO waarom werkt dit niet?
                        sliceViewer(new_img);                                               % TODO open new image in CompImg? or another viewer?
                        
                    case 'MRI'
                        
                        new_img = app.MRI .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off' 
                        title('Preview Extracted ROI - MRI','Color',[0.00,0.45,0.74]);
                        sliceViewer(new_img);                        
                        
                    case 'PET'
                        
                        new_img = app.PET .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off' 
                        title('Preview Extracted ROI - PET','Color',[0.00,0.45,0.74]);
                        sliceViewer(new_img);   
                        
                    otherwise
                        
                        % TODO Give an error
                        
                end
                
            elseif  empty_trans == false && empty_sagittal == false && empty_frontal == true    % Trans not empty, sagittal not empty, frontal empty
                
                app.bounding_box = app.mask_trans .* app.mask_sagittal;
                
                switch app.current_modality
                    case 'CT'
                        
                        new_img = app.CT .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off'             % TODO waarom werkt dit niet?
                        title('Preview Extracted ROI - CT','Color',[0.00,0.45,0.74]);       % TODO waarom werkt dit niet?
                        sliceViewer(new_img);                        
                        
                    case 'MRI'
                        
                        new_img = app.MRI .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off' 
                        title('Preview Extracted ROI - MRI','Color',[0.00,0.45,0.74]);
                        sliceViewer(new_img);   
                        
                    case 'PET'
                        
                        new_img = app.PET .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off' 
                        title('Preview Extracted ROI - MRI','Color',[0.00,0.45,0.74]);
                        sliceViewer(new_img); 
                        
                    otherwise
                        
                        % TODO give an error
                        
                end
                
            elseif  empty_trans == false && empty_sagittal == true && empty_frontal == false    % trans not empty, sagittal empty, frontal not empty
                
                app.bounding_box = app.mask_trans .* app.mask_frontal; 

                switch app.current_modality
                    case 'CT'
                        
                        new_img = app.CT .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off'             % TODO waarom werkt dit niet?
                        title('Preview Extracted ROI - CT','Color',[0.00,0.45,0.74]);       % TODO waarom werkt dit niet?
                        sliceViewer(new_img);                        
                        
                    case 'MRI'
                        
                        new_img = app.MRI .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off' 
                        title('Preview Extracted ROI - MRI','Color',[0.00,0.45,0.74]);
                        sliceViewer(new_img);   
                        
                    case 'PET'
                        
                        new_img = app.PET .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off' 
                        title('Preview Extracted ROI - MRI','Color',[0.00,0.45,0.74]);
                        sliceViewer(new_img); 
                        
                    otherwise
                        
                        % TODO give an error
                        
                end
                
            elseif  empty_trans == true && empty_sagittal == false && empty_frontal == false    % trans empty, sagittal not empty, frontal not empty
                
                app.bounding_box = app.mask_sagittal .* app.mask_frontal;
                
                switch app.current_modality
                    case 'CT'
                        
                        new_img = app.CT .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off'             % TODO waarom werkt dit niet?
                        title('Preview Extracted ROI - CT','Color',[0.00,0.45,0.74]);       % TODO waarom werkt dit niet?
                        sliceViewer(new_img);                        
                        
                    case 'MRI'
                        
                        new_img = app.MRI .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off' 
                        title('Preview Extracted ROI - MRI','Color',[0.00,0.45,0.74]);
                        sliceViewer(new_img);   
                        
                    case 'PET'
                        
                        new_img = app.PET .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off' 
                        title('Preview Extracted ROI - MRI','Color',[0.00,0.45,0.74]);
                        sliceViewer(new_img); 
                        
                    otherwise
                        
                        % TODO give an error
                        
                end
                
            elseif  empty_trans == false && empty_sagittal == true && empty_frontal == true     % trans not empty, sagittal empty, frontal empty
                
                app.bounding_box = app.mask_trans;
                
                switch app.current_modality
                    case 'CT'
                        
                        new_img = app.CT .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off'             % TODO waarom werkt dit niet?
                        title('Preview Extracted ROI - CT','Color',[0.00,0.45,0.74]);       % TODO waarom werkt dit niet?
                        sliceViewer(new_img);                        
                        
                    case 'MRI'
                        
                        new_img = app.MRI .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off' 
                        title('Preview Extracted ROI - MRI','Color',[0.00,0.45,0.74]);
                        sliceViewer(new_img);   
                        
                    case 'PET'
                        
                        new_img = app.PET .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off' 
                        title('Preview Extracted ROI - MRI','Color',[0.00,0.45,0.74]);
                        sliceViewer(new_img); 
                        
                    otherwise
                        
                        % TODO give an error
                        
                end
                
            elseif  empty_trans == true && empty_sagittal == false && empty_frontal == true     % trans empty, sagittal not empty, frontal empty
                
                app.bounding_box = app.mask_sagittal;  
                
                switch app.current_modality
                    case 'CT'
                        
                        new_img = app.CT .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off'             % TODO waarom werkt dit niet?
                        title('Preview Extracted ROI - CT','Color',[0.00,0.45,0.74]);       % TODO waarom werkt dit niet?
                        sliceViewer(new_img);                        
                        
                    case 'MRI'
                        
                        new_img = app.MRI .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off' 
                        title('Preview Extracted ROI - MRI','Color',[0.00,0.45,0.74]);
                        sliceViewer(new_img);   
                        
                    case 'PET'
                        
                        new_img = app.PET .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off' 
                        title('Preview Extracted ROI - MRI','Color',[0.00,0.45,0.74]);
                        sliceViewer(new_img); 
                        
                    otherwise
                        
                        % TODO give an error
                        
                end
                
            elseif  empty_trans == true && empty_sagittal == true && empty_frontal == false     % trans empty, sagittal empty, frontal not empty
                
                app.bounding_box = app.mask_frontal;
                
                switch app.current_modality
                    case 'CT'
                        
                        new_img = app.CT .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off'             % TODO waarom werkt dit niet?
                        title('Preview Extracted ROI - CT','Color',[0.00,0.45,0.74]);       % TODO waarom werkt dit niet?
                        sliceViewer(new_img);                        
                        
                    case 'MRI'
                        
                        new_img = app.MRI .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off' 
                        title('Preview Extracted ROI - MRI','Color',[0.00,0.45,0.74]);
                        sliceViewer(new_img);   
                        
                    case 'PET'
                        
                        new_img = app.PET .* app.bounding_box;
                        figure('Color',[0.15,0.15,0.15]); % 'NumberTitle','off' 
                        title('Preview Extracted ROI - MRI','Color',[0.00,0.45,0.74]);
                        sliceViewer(new_img); 
                        
                    otherwise
                        
                        % TODO give an error
                        
                end
                
            elseif  empty_trans == true && empty_sagittal == true && empty_frontal == true      % trans empty, sagittal empty, frontal empty
                
                app.Error.Value = 'Error!';
                app.Error.Value = [app.Error.Value; 'Draw and save bouding box(es) before finalisation'];
                
            else    
                
                app.Error.Value = 'Unknown error';
                app.Error.Value = [app.Error.Value, 'Something went wrong in FinalizeBoxMenu function'];
                
            end
            
            assignin('base','boundingbox_var',app.bounding_box) 
            
        end

        % Menu selected function: IsocenterMRIMenu
        function IsocenterMRIMenuSelected(app, event)
            
            % TODO open another UI to guide user through isocenter determintation
            % TODO make a box visible to change the threshold if necessary
            % TODO give an error if app.bounding_box is empty
            % TODO check if there are images/bounding box available
            
            % Ask user for input
                prompt = {'Enter percentile for threshold:'};
                inputtitle = 'Input';
                inputdim = [1 40];
                inputdef = {'95'};
                answer = inputdlg(prompt,inputtitle,inputdim,inputdef)
            
            % Use app.bounding_box
                MRI_ROI = app.bounding_box .* double(app.MRI);              % TODO: does this cause the image to be bad in line,775?
                MRI_ROI_N0 = MRI_ROI > 0;                                   % Find non-zero elements
            
            % Determine and apply threshold
                THRES_MRI = prctile(MRI_ROI(MRI_ROI_N0),str2double(answer{1}));                % Find threshold to get 5% highest uptake values (from non-zero elements)
                MRI_ROI_SEG = imbinarize(MRI_ROI,THRES_MRI);                % Apply the threshold
            
                cmpimg1 = CompImages(MRI_ROI,MRI_ROI_SEG);
                waitfor(cmpimg1);                                           % Wait for other window to be closed TODO: waitfor just before next image not here
                
                MRI_ROI_SEG_FILL = imfill(MRI_ROI_SEG,'holes');             % Fill holes
                
                %cmpimg2 = CompImages(MRI_ROI_SEG,MRI_ROI_SEG);
                %waitfor(cmpimg2);                                           % Wait for other window to be closed
                
                app.MRI_ROI_SEG_LARG = ExtractSegments(MRI_ROI_SEG_FILL,1);     % Extract the biggest segment TODO: deze functie in de app steken
                
                cmpimg3 = CompImages(MRI_ROI,app.MRI_ROI_SEG_LARG);
                waitfor(cmpimg3);

            % Extract information segment
                [labeledImage, numberOfBlobs] = bwlabeln(app.MRI_ROI_SEG_LARG); % TODO check if numberofblobs is equal to 1
                app.SegmMRI = regionprops3(labeledImage, MRI_ROI, ...
                    'Volume', 'Centroid', 'WeightedCentroid', ...
                    'MaxIntensity', 'MeanIntensity', 'MinIntensity', ...
                    'Orientation', 'BoundingBox','EquivDiameter');          % Extract info from MRI segment
            
            % Display result
                app.Error.Value = 'Pixel coordinates of MRI Isocenter:';
                app.Error.Value = [app.Error.Value; 'X = ', num2str(app.SegmMRI.Centroid(1))];
                app.Error.Value = [app.Error.Value; 'Y = ', num2str(app.SegmMRI.Centroid(2))];
                app.Error.Value = [app.Error.Value; 'Z = ', num2str(app.SegmMRI.Centroid(3))];
            
            % Display result on image
            
            % Show point on MRI an CT; TODO only show certain modalities if
            % they are loaded
            % TODO make this into a seperate function
            
                % Center volume
                xslice = app.SegmMRI.Centroid(1);
                yslice = app.SegmMRI.Centroid(2);
                zslice = app.SegmMRI.Centroid(3);
                % MRI
                % ...
                    figure('units','normalized','outerposition',[0 0 1 1])          % TODO dit herbekijken, snap de richtingen niet goed..., maak een viewer hiervoor, 
                    subplot(3,3,1)                                                  % TODO gebruik CompImages om zowel CT - MRI + isocenter en CT - PET
                    imshow(squeeze(app.MRI(:,round(xslice),:)),[0 app.MRI_max]);    % TODO schrijf kleine viewer voor deze figuur
                    view(90,90); axis on; hold on;
                    plot(app.SegmMRI.Centroid(3),app.SegmMRI.Centroid(2),'ob');
                    axis off; hold off;
                % ...
                    subplot(3,3,2)
                    imshow(squeeze(app.MRI(round(yslice),:,:)),[0 app.MRI_max]);
                    view(90,90); axis on; hold on;
                    plot(app.SegmMRI.Centroid(3),app.SegmMRI.Centroid(1),'ob');
                    axis off; hold off;
                % XY plane
                    subplot(3,3,3)
                    imshow(squeeze(app.MRI(:,:,round(zslice))),[0 app.MRI_max]);
                    axis on; hold on;
                    plot(app.SegmMRI.Centroid(1),app.SegmMRI.Centroid(2),'ob');
                    axis off; hold off;
                %CT
                % ...
                    subplot(3,3,4)
                    imshow(squeeze(app.CT(:,round(xslice),:)),[0.25*app.CT_max 0.50*app.CT_max]);
                    view(90,90); axis on; hold on;
                    plot(app.SegmMRI.Centroid(3),app.SegmMRI.Centroid(2),'ob');
                    axis off; hold off;
                % ...
                    subplot(3,3,5)
                    imshow(squeeze(app.CT(round(yslice),:,:)),[0.25*app.CT_max 0.50*app.CT_max]);
                    view(90,90); axis on; hold on;
                    plot(app.SegmMRI.Centroid(3),app.SegmMRI.Centroid(1),'ob');
                    axis off; hold off;
                % XY-plane
                    subplot(3,3,6)
                    imshow(squeeze(app.CT(:,:,round(zslice))),[0.25*app.CT_max 0.50*app.CT_max]);
                    axis on; hold on;
                    plot(app.SegmMRI.Centroid(1),app.SegmMRI.Centroid(2),'ob');
                    axis off; hold off;
                %PET
                % ...
                    subplot(3,3,7)
                    imshow(squeeze(app.PET(:,round(xslice),:)),[0 app.PET_max]);
                    view(90,90); axis on; hold on;
                    plot(app.SegmMRI.Centroid(3),app.SegmMRI.Centroid(2),'ob');
                    axis off; hold off;
                % ...
                    subplot(3,3,8)
                    imshow(squeeze(app.PET(round(yslice),:,:)),[0 app.PET_max]);
                    view(90,90); axis on; hold on;
                    plot(app.SegmMRI.Centroid(3),app.SegmMRI.Centroid(1),'ob');
                    axis off; hold off;
                % XY-plane
                    subplot(3,3,9)
                    imshow(squeeze(app.PET(:,:,round(zslice))),[0 app.PET_max]);
                    axis on; hold on;
                    plot(app.SegmMRI.Centroid(1),app.SegmMRI.Centroid(2),'ob');
                    axis off; hold off;
                    
            % Convert pixel coordinates to world coordinates
            
                SROW = [app.MRI_info.raw.srow_x; app.MRI_info.raw.srow_y; app.MRI_info.raw.srow_z; 0 0 0 1];                            % Transformatie matrix
                PixCoo = [app.SegmMRI.Centroid(1); app.SegmMRI.Centroid(2); app.SegmMRI.Centroid(3); 1];                                % Pixel coordinates
                
                WorCoo = SROW * PixCoo;
                
                % Display result
                app.Error.Value = [app.Error.Value; 'World coordinates of MRI Isocenter:';];
                app.Error.Value = [app.Error.Value; 'X = ', num2str(WorCoo(1))];
                app.Error.Value = [app.Error.Value; 'Y = ', num2str(WorCoo(2))];
                app.Error.Value = [app.Error.Value; 'Z = ', num2str(WorCoo(3))];
            
            % Convert world coordinates to Muriplan coordinates
            
                % TODO
            
        end

        % Menu selected function: IsocenterPETMenu
        function IsocenterPETMenuSelected(app, event)
            
            % Expand MRI Volume
                se = strel('sphere',10);                                    %TODO eventueel aanpassen + wat als er geen MRI volume is?
                PET_mask = imdilate(app.MRI_ROI_SEG_LARG, se);              %TODO change from inflated MRI volume to bounding box and work from there... less change of errors
                
            % PET ROI
                PET_ROI = double(app.PET) .* double(PET_mask);
                
                % TODO display PET and PET_ROI?
                % % figure()
                % % sliceViewer(PET,'DisplayRange', [0 pet_max])
                % % figure()
                % % sliceViewer(pet_roi,'DisplayRange', [0 pet_max])
                
            % PET max in ROI
                PET_ROI_MAX = max(max(max(PET_ROI)));
                
            % PET threshold
                %PET_ROI_N0 = PET_ROI > 0;
                THRES_PET = PET_ROI_MAX*0.90;
                
            % PET segmentation
                PET_ROI_SEG = imbinarize(PET_ROI,THRES_PET);
                
            % Display result
                cmpimg1 = CompImages(PET_ROI,PET_ROI_SEG);          % TODO why does it flicker so much?
                
            % Fill holes
                PET_ROI_SEG_FILL = imfill(PET_ROI_SEG, 'holes');
                
            % Display result
            
                
                % TODO display PET_ROI & PET_ROI_SEG
                % % figure()
                % % sliceViewer(pet_roi)
                % % figure()
                % % sliceViewer(pet_roi_segment,'DisplayRange',[0 1])
                % % figure()
                % % sliceViewer(pet_roi_segment)
                % % figure()
                % % sliceViewer(pet_roi_segment_filled)
                
            % Extract image information
                [labeledImage, numberOfBlobs] = bwlabeln(PET_ROI_SEG_FILL);
                blobMeasurements_PET = regionprops3(labeledImage, 'Volume');
            
            % Extract largest segment
                PET_ROI_SEG_LARG = ExtractSegments(PET_ROI_SEG_FILL,1);
                
            % Display result
                waitfor(cmpimg1)
                cmpimg2 = CompImages(PET_ROI,PET_ROI_SEG_LARG);
                
            % Extract center of largest segment
                [labeledImage, numberOfBlobs] = bwlabeln(PET_ROI_SEG_LARG); % TODO check if numberOfBlobs is 1
                app.SegmPET = regionprops3(labeledImage, PET_ROI, 'Volume', 'Centroid', 'WeightedCentroid','MaxIntensity','MeanIntensity','MinIntensity','Orientation','BoundingBox','EquivDiameter'); 
            
            % Display result
                app.Error.Value = 'Pixel coordinates of PET Isocenter:';
                app.Error.Value = [app.Error.Value; 'X = ', num2str(app.SegmPET.Centroid(1))];
                app.Error.Value = [app.Error.Value; 'Y = ', num2str(app.SegmPET.Centroid(2))];
                app.Error.Value = [app.Error.Value; 'Z = ', num2str(app.SegmPET.Centroid(3))];            
            
            
            % Display result on image
                waitfor(cmpimg2)

                % Center volume
                xslice = app.SegmPET.Centroid(1);
                yslice = app.SegmPET.Centroid(2);
                zslice = app.SegmPET.Centroid(3);  
                
                % MRI
                % ...
                    figure('units','normalized','outerposition',[0 0 1 1])          % TODO dit herbekijken, snap de richtingen niet goed..., maak een viewer hiervoor,  waarschijnlijk row/column major hier de oorzaak van?
                    subplot(3,3,1)                                                  % TODO gebruik CompImages om zowel CT - MRI + isocenter en CT - PET
                    imshow(squeeze(app.MRI(:,round(xslice),:)),[0 app.MRI_max]);    % TODO schrijf kleine viewer voor deze figuur
                    view(90,90); axis on; hold on;
                    plot(app.SegmMRI.Centroid(3),app.SegmMRI.Centroid(2),'ob');
                    plot(app.SegmPET.Centroid(3),app.SegmPET.Centroid(2),'or');
                    axis off; hold off;
                % ...
                    subplot(3,3,2)
                    imshow(squeeze(app.MRI(round(yslice),:,:)),[0 app.MRI_max]);
                    view(90,90); axis on; hold on;
                    plot(app.SegmMRI.Centroid(3),app.SegmMRI.Centroid(1),'ob');
                    plot(app.SegmPET.Centroid(3),app.SegmPET.Centroid(1),'or');
                    axis off; hold off;
                % XY plane
                    subplot(3,3,3)
                    imshow(squeeze(app.MRI(:,:,round(zslice))),[0 app.MRI_max]);
                    axis on; hold on;
                    plot(app.SegmMRI.Centroid(1),app.SegmMRI.Centroid(2),'ob');
                    plot(app.SegmPET.Centroid(1),app.SegmPET.Centroid(2),'or');
                    axis off; hold off;
                %CT
                % ...
                    subplot(3,3,4)
                    imshow(squeeze(app.CT(:,round(xslice),:)),[0.25*app.CT_max 0.50*app.CT_max]);
                    view(90,90); axis on; hold on;
                    plot(app.SegmMRI.Centroid(3),app.SegmMRI.Centroid(2),'ob');
                    plot(app.SegmPET.Centroid(3),app.SegmPET.Centroid(2),'or');
                    axis off; hold off;
                % ...
                    subplot(3,3,5)
                    imshow(squeeze(app.CT(round(yslice),:,:)),[0.25*app.CT_max 0.50*app.CT_max]);
                    view(90,90); axis on; hold on;
                    plot(app.SegmMRI.Centroid(3),app.SegmMRI.Centroid(1),'ob');
                    plot(app.SegmPET.Centroid(3),app.SegmPET.Centroid(1),'or');
                    axis off; hold off;
                % XY-plane
                    subplot(3,3,6)
                    imshow(squeeze(app.CT(:,:,round(zslice))),[0.25*app.CT_max 0.50*app.CT_max]);
                    axis on; hold on;
                    plot(app.SegmMRI.Centroid(1),app.SegmMRI.Centroid(2),'ob');
                    plot(app.SegmPET.Centroid(1),app.SegmPET.Centroid(2),'or');
                    axis off; hold off;
                %PET
                % ...
                    subplot(3,3,7)
                    imshow(squeeze(app.PET(:,round(xslice),:)),[0 app.PET_max]);
                    view(90,90); axis on; hold on;
                    plot(app.SegmMRI.Centroid(3),app.SegmMRI.Centroid(2),'ob');
                    plot(app.SegmPET.Centroid(3),app.SegmPET.Centroid(2),'or');
                    axis off; hold off;
                % ...
                    subplot(3,3,8)
                    imshow(squeeze(app.PET(round(yslice),:,:)),[0 app.PET_max]);
                    view(90,90); axis on; hold on;
                    plot(app.SegmMRI.Centroid(3),app.SegmMRI.Centroid(1),'ob');
                    plot(app.SegmPET.Centroid(3),app.SegmPET.Centroid(1),'or');
                    axis off; hold off;
                % XY-plane
                    subplot(3,3,9)
                    imshow(squeeze(app.PET(:,:,round(zslice))),[0 app.PET_max]);
                    axis on; hold on;
                    plot(app.SegmMRI.Centroid(1),app.SegmMRI.Centroid(2),'ob');
                    plot(app.SegmPET.Centroid(1),app.SegmPET.Centroid(2),'or');
                    axis off; hold off;
                
                    
            % Convert pixel coordinates to world coordinates
                    SROW = [app.PET_info.raw.srow_x; app.PET_info.raw.srow_y; app.PET_info.raw.srow_z; 0 0 0 1];
                    PixCoo = [app.SegmPET.Centroid(1); app.SegmPET.Centroid(2); app.SegmPET.Centroid(3); 1];
                    
                    WorCoo = SROW * PixCoo;
            % Display result
                    app.Error.Value = [app.Error.Value; 'World coordinates of PET Isocenter:';];
                    app.Error.Value = [app.Error.Value; 'X = ', num2str(WorCoo(1))];    %TODO eventueel aanpassen zodat verschil is tussen MRI en PET (WorCoo, SROW etc)
                    app.Error.Value = [app.Error.Value; 'Y = ', num2str(WorCoo(2))];
                    app.Error.Value = [app.Error.Value; 'Z = ', num2str(WorCoo(3))];   
                    
            % Convert world coordinates to Muriplan coordinates
            
                % TODO


% DEEL MRI    
%             % Show point on MRI an CT; TODO only show certain modalities if
%             % they are loaded
%             % TODO make this into a seperate function
%             

%                 % MRI
%                 % ...
%                     figure('units','normalized','outerposition',[0 0 1 1])          % TODO dit herbekijken, snap de richtingen niet goed..., maak een viewer hiervoor, 
%                     subplot(3,3,1)                                                  % TODO gebruik CompImages om zowel CT - MRI + isocenter en CT - PET
%                     imshow(squeeze(app.MRI(:,round(xslice),:)),[0 app.MRI_max]);    % TODO schrijf kleine viewer voor deze figuur
%                     view(90,90); axis on; hold on;
%                     plot(app.SegmMRI.Centroid(3),app.SegmMRI.Centroid(2),'ob');
%                     axis off; hold off;
%                 % ...

%                      
         
% 
% % Show point on MRI an CT
% % Center volume
% xslice = blobMeasurements_PET.Centroid(1);
% yslice = blobMeasurements_PET.Centroid(2);
% zslice = blobMeasurements_PET.Centroid(3);
% % MRI
% % 
% figure()
% subplot(3,3,1)
% imshow(squeeze(MRI(:,round(xslice),:)),[0 25000]);
% view(90,90)
% axis on
% hold on
% plot(blobMeasurements_PET.Centroid(3),blobMeasurements_PET.Centroid(2),'or')
% plot(blobMeasurements_MRI.Centroid(3),blobMeasurements_MRI.Centroid(2),'ob')
% axis off
% hold off
% % 
% subplot(3,3,2)
% imshow(squeeze(MRI(round(yslice),:,:)),[0 25000]);
% view(90,90)
% axis on
% hold on
% plot(blobMeasurements_PET.Centroid(3),blobMeasurements_PET.Centroid(1),'or')
% plot(blobMeasurements_MRI.Centroid(3),blobMeasurements_MRI.Centroid(1),'ob')
% axis off
% hold off
% % XY-plane
% subplot(3,3,3)
% imshow(squeeze(MRI(:,:,round(zslice))),[0 25000]);
% axis on
% hold on
% plot(blobMeasurements_PET.Centroid(1),blobMeasurements_PET.Centroid(2),'or')
% plot(blobMeasurements_MRI.Centroid(1),blobMeasurements_MRI.Centroid(2),'ob')
% axis off
% hold off
% %CT
% CT_max = max(max(max(CT)));
% %
% subplot(3,3,4)
% imshow(squeeze(CT(:,round(xslice),:)),[0.35*CT_max 0.50*CT_max]);
% view(90,90)
% axis on
% hold on
% plot(blobMeasurements_PET.Centroid(3),blobMeasurements_PET.Centroid(2),'or')
% plot(blobMeasurements_MRI.Centroid(3),blobMeasurements_MRI.Centroid(2),'ob')
% axis off
% hold off
% % 
% subplot(3,3,5)
% imshow(squeeze(CT(round(yslice),:,:)),[0.35*CT_max 0.50*CT_max]);
% view(90,90)
% axis on
% hold on
% plot(blobMeasurements_PET.Centroid(3),blobMeasurements_PET.Centroid(1),'or')
% plot(blobMeasurements_MRI.Centroid(3),blobMeasurements_MRI.Centroid(1),'ob')
% axis off
% hold off
% % XY-plane
% subplot(3,3,6)
% imshow(squeeze(CT(:,:,round(zslice))),[0.35*CT_max 0.50*CT_max]);
% axis on
% hold on
% plot(blobMeasurements_PET.Centroid(1),blobMeasurements_PET.Centroid(2),'or')
% plot(blobMeasurements_MRI.Centroid(1),blobMeasurements_MRI.Centroid(2),'ob')
% axis off
% hold off
% %PET
% %
% subplot(3,3,7)
% imshow(squeeze(PET(:,round(xslice),:)));
% view(90,90)
% axis on
% hold on
% plot(blobMeasurements_PET.Centroid(3),blobMeasurements_PET.Centroid(2),'or')
% plot(blobMeasurements_MRI.Centroid(3),blobMeasurements_MRI.Centroid(2),'ob')
% axis off
% hold off
% % 
% subplot(3,3,8)
% imshow(squeeze(PET(round(yslice),:,:)));
% view(90,90)
% axis on
% hold on
% plot(blobMeasurements_PET.Centroid(3),blobMeasurements_PET.Centroid(1),'or')
% plot(blobMeasurements_MRI.Centroid(3),blobMeasurements_MRI.Centroid(1),'ob')
% axis off
% hold off
% % XY-plane
% subplot(3,3,9)
% imshow(squeeze(PET(:,:,round(zslice))));
% axis on
% hold on
% plot(blobMeasurements_PET.Centroid(1),blobMeasurements_PET.Centroid(2),'or')
% plot(blobMeasurements_MRI.Centroid(1),blobMeasurements_MRI.Centroid(2),'ob')
% axis off
% hold off
% 
% 
% 
% 
% figure()
% subplot(1,2,1)
% imshowpair(squeeze(CT(:,:,round(zslice))),squeeze(MRI(:,:,round(zslice))))
% axis on
% hold on
% plot(blobMeasurements_PET.Centroid(1),blobMeasurements_PET.Centroid(2),'or')
% plot(blobMeasurements_MRI.Centroid(1),blobMeasurements_MRI.Centroid(2),'ob')
% axis off
% hold off
% subplot(1,2,2)
% imshowpair(squeeze(MRI(:,round(xslice),:)),squeeze(CT(:,round(xslice),:)))
% view(90,90)
% axis on
% hold on
% plot(blobMeasurements_PET.Centroid(3),blobMeasurements_PET.Centroid(2),'or')
% plot(blobMeasurements_MRI.Centroid(3),blobMeasurements_MRI.Centroid(2),'ob')
% axis off
% hold off
% 
% % TODO: plaats 10x10x10 box rond punt en toon
% 
% % Convert pixel center to world center
% 
% SROW = [CT_info.raw.srow_x; CT_info.raw.srow_y; CT_info.raw.srow_z; 0 0 0 1];
% P_COOR = [blobMeasurements_PET.Centroid(1); blobMeasurements_PET.Centroid(2); blobMeasurements_PET.Centroid(3); 1];
% 
% R_COOR = SROW * P_COOR;
% 
% % Convert world coordinates to Muriplan coordinates
% 
% % MURI_COOR


% % TODO:  'ScrollWheelDuringDraw' misschien handig voor tekenen ROI in app?

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DosePaintingSamDoncheUIFigure = uifigure('Visible', 'off');
            app.DosePaintingSamDoncheUIFigure.Name = 'Dose Painting - Sam Donche';
            app.DosePaintingSamDoncheUIFigure.KeyPressFcn = createCallbackFcn(app, @ViewerControls, true);
            app.FileMenu = uimenu(app.DosePaintingSamDoncheUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadCTMenu = uimenu(app.FileMenu);
            app.LoadCTMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadCT, true);
            app.LoadCTMenu.Text = 'Load CT';
            app.LoadMRIMenu = uimenu(app.FileMenu);
            app.LoadMRIMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMRI, true);
            app.LoadMRIMenu.Text = 'Load MRI';
            app.LoadPETMenu = uimenu(app.FileMenu);
            app.LoadPETMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPET, true);
            app.LoadPETMenu.Text = 'Load PET';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Text = 'Save';
            app.ViewMenu = uimenu(app.DosePaintingSamDoncheUIFigure);
            app.ViewMenu.Text = 'View';
            app.ViewCTMenu = uimenu(app.ViewMenu);
            app.ViewCTMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewCT, true);
            app.ViewCTMenu.Text = 'CT';
            app.ViewMRIMenu = uimenu(app.ViewMenu);
            app.ViewMRIMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMRI, true);
            app.ViewMRIMenu.Text = 'MRI';
            app.ViewPETMenu = uimenu(app.ViewMenu);
            app.ViewPETMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewPET, true);
            app.ViewPETMenu.Text = 'PET';
            app.ViewCTMRIMenu = uimenu(app.ViewMenu);
            app.ViewCTMRIMenu.Separator = 'on';
            app.ViewCTMRIMenu.Text = 'CT - MRI';
            app.ViewCTPETMenu = uimenu(app.ViewMenu);
            app.ViewCTPETMenu.Text = 'CT - PET';
            app.BoundingBoxMenu = uimenu(app.DosePaintingSamDoncheUIFigure);
            app.BoundingBoxMenu.Text = 'Bounding Box';
            app.DrawBoxMenu = uimenu(app.BoundingBoxMenu);
            app.DrawBoxMenu.Text = 'Draw Box';
            app.DrawBoxTransverseViewMenu = uimenu(app.DrawBoxMenu);
            app.DrawBoxTransverseViewMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawBoxTrans, true);
            app.DrawBoxTransverseViewMenu.Text = 'Transverse View';
            app.DrawBoxSagittalViewMenu = uimenu(app.DrawBoxMenu);
            app.DrawBoxSagittalViewMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawBoxSagittal, true);
            app.DrawBoxSagittalViewMenu.Text = 'Sagittal View';
            app.DrawBoxFrontalViewMenu = uimenu(app.DrawBoxMenu);
            app.DrawBoxFrontalViewMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawBoxFrontal, true);
            app.DrawBoxFrontalViewMenu.Text = 'Frontal View';
            app.SaveBoxMenu = uimenu(app.BoundingBoxMenu);
            app.SaveBoxMenu.Text = 'Save Box';
            app.SaveBoxTransverseMenu = uimenu(app.SaveBoxMenu);
            app.SaveBoxTransverseMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBoxTrans, true);
            app.SaveBoxTransverseMenu.Text = 'Transverse View';
            app.SaveBoxSagittalMenu = uimenu(app.SaveBoxMenu);
            app.SaveBoxSagittalMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBoxSagittal, true);
            app.SaveBoxSagittalMenu.Text = 'Sagittal View';
            app.SaveBoxFrontalMenu = uimenu(app.SaveBoxMenu);
            app.SaveBoxFrontalMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBoxFrontal, true);
            app.SaveBoxFrontalMenu.Text = 'Frontal View';
            app.FinaliseBoxMenu = uimenu(app.BoundingBoxMenu);
            app.FinaliseBoxMenu.MenuSelectedFcn = createCallbackFcn(app, @FinaliseBox, true);
            app.FinaliseBoxMenu.Separator = 'on';
            app.FinaliseBoxMenu.Text = 'Finalise Box';
            app.ClearMenu = uimenu(app.BoundingBoxMenu);
            app.ClearMenu.Text = 'Clear';
            app.IsocenterMenu = uimenu(app.DosePaintingSamDoncheUIFigure);
            app.IsocenterMenu.Text = 'Isocenter';
            app.IsocenterMRIMenu = uimenu(app.IsocenterMenu);
            app.IsocenterMRIMenu.MenuSelectedFcn = createCallbackFcn(app, @IsocenterMRIMenuSelected, true);
            app.IsocenterMRIMenu.Text = 'MRI';
            app.IsocenterPETMenu = uimenu(app.IsocenterMenu);
            app.IsocenterPETMenu.MenuSelectedFcn = createCallbackFcn(app, @IsocenterPETMenuSelected, true);
            app.IsocenterPETMenu.Text = 'PET';
            app.ErrorPanel = uipanel(app.DosePaintingSamDoncheUIFigure);
            app.ErrorPanel.Tooltip = {''};
            app.Label_2 = uilabel(app.ErrorPanel);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '';
            app.Error = uitextarea(app.ErrorPanel);
            app.Error.Editable = 'off';
            app.Error.Enable = 'off';
            app.InfoTransverseView = uipanel(app.DosePaintingSamDoncheUIFigure);
            app.InfoTransverseView.Tooltip = {''};
            app.InfoTransverseView.Title = 'Transverse View';
            app.SliceEditFieldLabel = uilabel(app.InfoTransverseView);
            app.SliceEditFieldLabel.Text = 'Slice';
            app.SliceTransField = uieditfield(app.InfoTransverseView, 'numeric');
            app.SliceTransField.ValueChangedFcn = createCallbackFcn(app, @TransSliceFieldValue, true);
            app.InfoTransMRI = uitextarea(app.InfoTransverseView);
            app.InfoTransMRI.Editable = 'off';
            app.InfoTransMRI.Enable = 'off';
            app.InfoTransPET = uitextarea(app.InfoTransverseView);
            app.InfoTransPET.Editable = 'off';
            app.InfoTransPET.Enable = 'off';
            app.InfoTransCT = uitextarea(app.InfoTransverseView);
            app.InfoTransCT.Editable = 'off';
            app.InfoTransCT.Enable = 'off';
            app.TransverseViewPanel = uipanel(app.DosePaintingSamDoncheUIFigure);
            app.TransverseViewPanel.Title = 'Transverse View';
            app.UIAxesTrans = uiaxes(app.TransverseViewPanel);
            title(app.UIAxesTrans, '')
            xlabel(app.UIAxesTrans, '')
            ylabel(app.UIAxesTrans, '')
            app.SagittalViewPanel = uipanel(app.DosePaintingSamDoncheUIFigure);
            app.SagittalViewPanel.Title = 'Sagittal View';
            app.UIAxesSagittal = uiaxes(app.SagittalViewPanel);
            title(app.UIAxesSagittal, '')
            xlabel(app.UIAxesSagittal, '')
            ylabel(app.UIAxesSagittal, '')
            app.FrontalViewPanel = uipanel(app.DosePaintingSamDoncheUIFigure);
            app.FrontalViewPanel.Title = 'Frontal View';
            app.UIAxesFrontal = uiaxes(app.FrontalViewPanel);
            title(app.UIAxesFrontal, '')
            xlabel(app.UIAxesFrontal, '')
            ylabel(app.UIAxesFrontal, '')
            app.InfoSagittalView = uipanel(app.DosePaintingSamDoncheUIFigure);
            app.InfoSagittalView.Tooltip = {''};
            app.InfoSagittalView.Title = 'Sagittal View';
            app.SliceEditFieldLabel_2 = uilabel(app.InfoSagittalView);
            app.SliceEditFieldLabel_2.Text = 'Slice';
            app.SliceSagittalField = uieditfield(app.InfoSagittalView, 'numeric');
            app.SliceSagittalField.ValueChangedFcn = createCallbackFcn(app, @SagittalSliceFieldValue, true);
            app.InfoSagittalMRI = uitextarea(app.InfoSagittalView);
            app.InfoSagittalMRI.Editable = 'off';
            app.InfoSagittalMRI.Enable = 'off';
            app.InfoSagittalPET = uitextarea(app.InfoSagittalView);
            app.InfoSagittalPET.Editable = 'off';
            app.InfoSagittalPET.Enable = 'off';
            app.InfoSagittalCT = uitextarea(app.InfoSagittalView);
            app.InfoSagittalCT.Editable = 'off';
            app.InfoSagittalCT.Enable = 'off';
            app.InfoFrontalView = uipanel(app.DosePaintingSamDoncheUIFigure);
            app.InfoFrontalView.Tooltip = {''};
            app.InfoFrontalView.Title = 'Frontal View';
            app.SliceEditFieldLabel_3 = uilabel(app.InfoFrontalView);
            app.SliceEditFieldLabel_3.Text = 'Slice';
            app.SliceFrontalField = uieditfield(app.InfoFrontalView, 'numeric');
            app.SliceFrontalField.ValueChangedFcn = createCallbackFcn(app, @FrontalSliceFieldValue, true);
            app.InfoFrontalMRI = uitextarea(app.InfoFrontalView);
            app.InfoFrontalMRI.Editable = 'off';
            app.InfoFrontalMRI.Enable = 'off';
            app.InfoFrontalPET = uitextarea(app.InfoFrontalView);
            app.InfoFrontalPET.Editable = 'off';
            app.InfoFrontalPET.Enable = 'off';
            app.InfoFrontalCT = uitextarea(app.InfoFrontalView);
            app.InfoFrontalCT.Editable = 'off';
            app.InfoFrontalCT.Enable = 'off';
            % Show the figure after all components are created
            app.DosePaintingSamDoncheUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DosePainting(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DosePaintingSamDoncheUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)Start(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DosePaintingSamDoncheUIFigure)
        end
    end
end